classdef FinalApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SelectTDM                  matlab.ui.control.Button
        SelectCDM                  matlab.ui.control.Button
        Sender                     matlab.ui.control.Button
        EditField1                 matlab.ui.control.EditField
        SelectFilesandPressButton  matlab.ui.control.Button
        SelectAnUserDropDownLabel  matlab.ui.control.Label
        SelectAnUserDropDown       matlab.ui.control.DropDown
        NowPlayingEditFieldLabel   matlab.ui.control.Label
        EditField                  matlab.ui.control.EditField
        PlotSender                 matlab.ui.control.UIAxes
        PlotReceiver               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        name;
        path;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectTDM
        function SelectTDMButtonPushed(app, event)
            set(app.SelectCDM, 'Enable', 'off');
            app.SelectTDM.Text = 'TDM Selected';
        end

        % Button pushed function: SelectCDM
        function SelectCDMButtonPushed(app, event)
            set(app.SelectTDM, 'Enable', 'off');
            app.SelectCDM.Text = 'CDM Selected';
        end

        % Button pushed function: Sender
        function SenderButtonPushed(app, event)
            [app.name, app.path] = uigetfile(".flac","Select 4 Files for 4 users", "MultiSelect","on");
            app.name
            figure(app.UIFigure);
        end

        % Button pushed function: SelectFilesandPressButton
        function SelectFilesandPressButtonPushed(app, event)
            app.SelectFilesandPressButton.Text = 'Sending';
            if strcmpi(app.SelectTDM.Text, 'TDM Selected')
                tdm_function(app.name, app.path);
            end
            if strcmpi(app.SelectCDM.Text , 'CDM Selected')
                cdm_function(app.name,app.path);
            end
            app.SelectFilesandPressButton.Text = 'Received!!!';            
            
        end

        % Value changed function: SelectAnUserDropDown
        function SelectAnUserDropDownValueChanged(app, event)
            value = app.SelectAnUserDropDown.Value;
            switch value
                case 'User 1'
                    [x,f] = audioread('sender1.flac');
                    y = audioread('receiver1.flac');
                    plot(app.PlotSender,x);
                    plot(app.PlotReceiver,y);
                    
                    title(app.PlotSender,'Sender 1');
                    title(app.PlotReceiver,'Receiver 1');
                    app.EditField.Value = 'Sender Audio';
                    soundsc(x,f); pause(5);
                    app.EditField.Value = 'Receiver Audio';
                    soundsc(y,f); pause(4);
                    
                case 'User 2'
                    [x,f] = audioread('sender2.flac');
                    y = audioread('receiver2.flac');
                    plot(app.PlotSender,x);
                    plot(app.PlotReceiver,y);
                    
                    title(app.PlotSender,'Sender 2');
                    title(app.PlotReceiver,'Receiver 2');
                    
                    app.EditField.Value = 'Sender Audio';
                    soundsc(x,f); pause(5);
                    app.EditField.Value = 'Receiver Audio';
                    soundsc(y,f); pause(4);
                case 'User 3'
                    [x,f] = audioread('sender3.flac');
                    y = audioread('receiver3.flac');
                    plot(app.PlotSender,x);
                    plot(app.PlotReceiver,y);
                    
                    title(app.PlotSender,'Sender 3');
                    title(app.PlotReceiver,'Receiver 3');
                    
                    
                    app.EditField.Value = 'Sender Audio';
                    soundsc(x,f); pause(5);
                    app.EditField.Value = 'Receiver Audio';
                    soundsc(y,f); pause(4);
                case 'User 4'
                    [x,f] = audioread('sender4.flac');
                    y = audioread('receiver4.flac');
                    plot(app.PlotSender,x);
                    plot(app.PlotReceiver,y);
                    
                    title(app.PlotSender,'Sender 4');
                    title(app.PlotReceiver,'Receiver 4');
                    
                    
                    app.EditField.Value = 'Sender Audio';
                    soundsc(x,f); pause(5);
                    app.EditField.Value = 'Receiver Audio';
                    soundsc(y,f); pause(4);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectTDM = uibutton(app.UIFigure, 'push');
            app.SelectTDM.ButtonPushedFcn = createCallbackFcn(app, @SelectTDMButtonPushed, true);
            app.SelectTDM.Text = 'Select TDM';
            app.SelectCDM = uibutton(app.UIFigure, 'push');
            app.SelectCDM.ButtonPushedFcn = createCallbackFcn(app, @SelectCDMButtonPushed, true);
            app.SelectCDM.Text = {'Select CDM'; ''};
            app.Sender = uibutton(app.UIFigure, 'push');
            app.Sender.ButtonPushedFcn = createCallbackFcn(app, @SenderButtonPushed, true);
            app.Sender.Text = {'Select'; 'File'};
            app.EditField1 = uieditfield(app.UIFigure, 'text');
            app.EditField1.Value = 'Senders';
            app.SelectFilesandPressButton = uibutton(app.UIFigure, 'push');
            app.SelectFilesandPressButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFilesandPressButtonPushed, true);
            app.SelectFilesandPressButton.Text = {'Select Files '; 'and Press'; ''};
            app.SelectAnUserDropDownLabel = uilabel(app.UIFigure);
            app.SelectAnUserDropDownLabel.Text = 'Select An User';
            app.SelectAnUserDropDown = uidropdown(app.UIFigure);
            app.SelectAnUserDropDown.Items = {'List', 'User 1', 'User 2', 'User 3', 'User 4', ''};
            app.SelectAnUserDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectAnUserDropDownValueChanged, true);
            app.SelectAnUserDropDown.Value = 'List';
            app.NowPlayingEditFieldLabel = uilabel(app.UIFigure);
            app.NowPlayingEditFieldLabel.Text = 'Now Playing';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.PlotSender = uiaxes(app.UIFigure);
            title(app.PlotSender, 'Title')
            app.PlotReceiver = uiaxes(app.UIFigure);
            title(app.PlotReceiver, 'Title')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end